classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        InsrerlecodebarresEditField     matlab.ui.control.NumericEditField
        InsrerlecodebarresEditFieldLabel  matlab.ui.control.Label
        DmarerlasimulationButton        matlab.ui.control.Button
        TempsdesimulationsSlider        matlab.ui.control.Slider
        TempsdesimulationsSliderLabel   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Phi_cEditField                  matlab.ui.control.NumericEditField
        Phi_cEditFieldLabel             matlab.ui.control.Label
        DistancexducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistancexducodebarrescmEditFieldLabel  matlab.ui.control.Label
        DistanceyducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistanceyducodebarrescmLabel    matlab.ui.control.Label
        Alpha_cEditField                matlab.ui.control.NumericEditField
        Alpha_cEditFieldLabel           matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        TotaldelafactureEditField       matlab.ui.control.NumericEditField
        TotaldelafactureEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarerlasimulationButton
        function DmarerlasimulationButtonPushed(app, event)
            assignin("base", "dx_c",app.DistancexducodebarrescmEditField.Value);
            assignin("base", "dy_c",app.DistanceyducodebarrescmEditField.Value);
            assignin("base", "alpha_c",app.Alpha_cEditField.Value);
            assignin("base", "phi_c",app.Phi_cEditField.Value);
            assignin("base", "barres",app.InsrerlecodebarresEditField);
            code=sim("Frame.slx", "StopTime",num2str(app.TempsdesimulationsSlider.Value));
            code=transpose(code);
            code=code(1100:1530);
            for i=1:(100)
            code=[code;code(1,:)];
            end
            saveas(imshow(code), "code.png")
            I = imread("code.png");
            msg = readBarcode(I);
            disp(msg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Alpha_cEditFieldLabel = uilabel(app.Tab);
            app.Alpha_cEditFieldLabel.Text = 'Alpha_c';
            app.Alpha_cEditField = uieditfield(app.Tab, 'numeric');
            app.DistanceyducodebarrescmLabel = uilabel(app.Tab);
            app.DistanceyducodebarrescmLabel.Text = 'Distance y du code-barres (cm)';
            app.DistanceyducodebarrescmEditField = uieditfield(app.Tab, 'numeric');
            app.DistancexducodebarrescmEditFieldLabel = uilabel(app.Tab);
            app.DistancexducodebarrescmEditFieldLabel.Text = 'Distance x du code-barres (cm)';
            app.DistancexducodebarrescmEditField = uieditfield(app.Tab, 'numeric');
            app.Phi_cEditFieldLabel = uilabel(app.Tab);
            app.Phi_cEditFieldLabel.Text = 'Phi_c';
            app.Phi_cEditField = uieditfield(app.Tab, 'numeric');
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'ULAVAL.png';
            app.TempsdesimulationsSliderLabel = uilabel(app.Tab);
            app.TempsdesimulationsSliderLabel.Text = 'Temps de simulation(s)';
            app.TempsdesimulationsSlider = uislider(app.Tab);
            app.DmarerlasimulationButton = uibutton(app.Tab, 'push');
            app.DmarerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarerlasimulationButtonPushed, true);
            app.DmarerlasimulationButton.Text = 'Démarer la simulation';
            app.InsrerlecodebarresEditFieldLabel = uilabel(app.Tab);
            app.InsrerlecodebarresEditFieldLabel.Text = 'Insérer le code-barres';
            app.InsrerlecodebarresEditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Nom de l''article'; 'Prix'};
            app.UITable.RowName = {};
            app.TotaldelafactureEditFieldLabel = uilabel(app.Tab2);
            app.TotaldelafactureEditFieldLabel.Text = 'Total de la facture';
            app.TotaldelafactureEditField = uieditfield(app.Tab2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end